classdef kitaev_disperzija_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        tDSliderLabel   matlab.ui.control.Label
        tDSlider        matlab.ui.control.Slider
        mutSliderLabel  matlab.ui.control.Label
        mutSlider       matlab.ui.control.Slider
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: tDSlider
        function tDSliderValueChanging(app, event)
            D=1; 
            t= event.Value*D;
            mu = t*app.mutSlider.Value; 
            ka = linspace(-pi, pi, 1000);
            E_k1 = zeros(1, length(ka));
            E_k2 = zeros(1, length(ka));
            for j = 1:length(ka)
                E_k1(j) = sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                E_k2(j) = -sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                %(kako ih dobiti da budu u odnosu o ka izmatrice?)
            end
            plot(app.UIAxes, ka, E_k1/t, ka, E_k2/t, 'b')
        end

        % Value changing function: mutSlider
        function mutSliderValueChanging(app, event)
            D=1; 
            t= app.tDSlider.Value*D;
            mu = t*event.Value;
            ka = linspace(-pi, pi, 1000);
            E_k1 = zeros(1, length(ka));
            E_k2 = zeros(1, length(ka));
            for j = 1:length(ka)
                E_k1(j) = sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                E_k2(j) = -sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                %(kako ih dobiti da budu u odnosu o ka izmatrice?)
            end
            plot(app.UIAxes, ka, E_k1/t, ka, E_k2/t, 'b')
        end

        % Value changed function: mutSlider
        function mutSliderValueChanged(app, event)
            D=1; 
            t= app.tDSlider.Value*D;
            mu = t*app.mutSlider.Value; 
            ka = linspace(-pi, pi, 1000);
            E_k1 = zeros(1, length(ka));
            E_k2 = zeros(1, length(ka));
            for j = 1:length(ka)
                E_k1(j) = sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                E_k2(j) = -sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                %(kako ih dobiti da budu u odnosu o ka izmatrice?)
            end
            plot(app.UIAxes, ka, E_k1/t, ka, E_k2/t, 'b')
        end

        % Value changed function: tDSlider
        function tDSliderValueChanged(app, event)
            D=1; 
            t= app.tDSlider.Value*D;
            mu = t*app.mutSlider.Value; 
            ka = linspace(-pi, pi, 1000);
            E_k1 = zeros(1, length(ka));
            E_k2 = zeros(1, length(ka));
            for j = 1:length(ka)
                E_k1(j) = sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                E_k2(j) = -sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                %(kako ih dobiti da budu u odnosu o ka izmatrice?)
            end
            plot(app.UIAxes, ka, E_k1/t, ka, E_k2/t, 'b')
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            D=1; 
            t= app.tDSlider.Value*D;
            mu = t*app.mutSlider.Value; 
            ka = linspace(-pi, pi, 1000);
            E_k1 = zeros(1, length(ka));
            E_k2 = zeros(1, length(ka));
            for j = 1:length(ka)
                E_k1(j) = sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                E_k2(j) = -sqrt((mu+2.*t.*cos(ka(j))).^2+4.*D.^2.*(sin(ka(j))).^2);
                %(kako ih dobiti da budu u odnosu o ka izmatrice?)
            end
            plot(app.UIAxes, ka, E_k1/t, ka, E_k2/t, 'b')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Disperzija')
            xlabel(app.UIAxes, 'ka')
            ylabel(app.UIAxes, 'E/t')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XTick = [-3 -2 -1 0 1 2 3];
            app.UIAxes.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.mutSlider = uislider(app.UIFigure);
            app.mutSlider.Limits = [-2.5 2.5];
            app.mutSlider.ValueChangedFcn = createCallbackFcn(app, @mutSliderValueChanged, true);
            app.mutSlider.ValueChangingFcn = createCallbackFcn(app, @mutSliderValueChanging, true);
            app.mutSlider.Value = 2.5;
            app.mutSliderLabel = uilabel(app.UIFigure);
            app.mutSliderLabel.Text = 'mu/t';
            app.tDSlider = uislider(app.UIFigure);
            app.tDSlider.Limits = [0 5];
            app.tDSlider.ValueChangedFcn = createCallbackFcn(app, @tDSliderValueChanged, true);
            app.tDSlider.ValueChangingFcn = createCallbackFcn(app, @tDSliderValueChanging, true);
            app.tDSlider.Value = 1;
            app.tDSliderLabel = uilabel(app.UIFigure);
            app.tDSliderLabel.Text = 't/D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kitaev_disperzija_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end