classdef kitaev_spektar_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        NSpinner       matlab.ui.control.Spinner
        NSpinnerLabel  matlab.ui.control.Label
        tDSlider       matlab.ui.control.Slider
        tDSliderLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: tDSlider
        function tDSliderValueChanged(app, event)
        N = app.NSpinner.Value;
        D = 1;
        t = app.tDSlider.Value*D;
        mu = linspace(-3*t,3*t,1001); 
        eigE=zeros(length(mu),2*N);
        for k = 1:length(mu)
            Em=[-mu(k) 0; 0 mu(k)];
            Tm=[-t -D; D t];
        
            Hkc = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
        
            [Vxx,Dxx] = eig(Hkc);
            eigE(k,:) = diag(Dxx);
        end
        plot(app.UIAxes, mu/t, eigE/D, 'b')
        end

        % Value changing function: tDSlider
        function tDSliderValueChanging(app, event)
            N = app.NSpinner.Value;
            D = 1;
            t = event.Value*D;
            mu = linspace(-3*t,3*t,1001); 
            eigE=zeros(length(mu),2*N);
            for k = 1:length(mu)
                Em=[-mu(k) 0; 0 mu(k)];
                Tm=[-t -D; D t];
            
                Hkc = kron(diag(ones(1,N)),Em)... 
                    +kron(diag(ones(1,N-1),1),Tm)...
                    +kron(diag(ones(1,N-1),-1),Tm');
            
                [Vxx,Dxx] = eig(Hkc);
                eigE(k,:) = diag(Dxx);
            end
            plot(app.UIAxes, mu/t, eigE/D, 'b')
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            N = app.NSpinner.Value;
            D = 1;
            t = app.tDSlider.Value*D;
            mu = linspace(-3*t,3*t,1001);  
            eigE=zeros(length(mu),2*N);
            for k = 1:length(mu)
                Em=[-mu(k) 0; 0 mu(k)];
                Tm=[-t -D; D t];
            
                Hkc = kron(diag(ones(1,N)),Em)... 
                    +kron(diag(ones(1,N-1),1),Tm)...
                    +kron(diag(ones(1,N-1),-1),Tm');
            
                [Vxx,Dxx] = eig(Hkc);
                eigE(k,:) = diag(Dxx);
            end
            plot(app.UIAxes, mu/t, eigE/D, 'b')
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            N = app.NSpinner.Value;
            D = 1;
            t = app.tDSlider.Value*D;
            mu = linspace(-3*t,3*t,1001); 
            eigE=zeros(length(mu),2*N);
            for k = 1:length(mu)
                Em=[-mu(k) 0; 0 mu(k)];
                Tm=[-t -D; D t];
            
                Hkc = kron(diag(ones(1,N)),Em)... 
                    +kron(diag(ones(1,N-1),1),Tm)...
                    +kron(diag(ones(1,N-1),-1),Tm');
            
                [Vxx,Dxx] = eig(Hkc);
                eigE(k,:) = diag(Dxx);
            end
            plot(app.UIAxes, mu/t, eigE/D, 'b')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spektar')
            xlabel(app.UIAxes, 'mu/t')
            ylabel(app.UIAxes, 'E/D')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.tDSliderLabel = uilabel(app.UIFigure);
            app.tDSliderLabel.Text = 't/D';
            app.tDSlider = uislider(app.UIFigure);
            app.tDSlider.Limits = [0 5];
            app.tDSlider.ValueChangedFcn = createCallbackFcn(app, @tDSliderValueChanged, true);
            app.tDSlider.ValueChangingFcn = createCallbackFcn(app, @tDSliderValueChanging, true);
            app.tDSlider.Value = 1;
            app.NSpinnerLabel = uilabel(app.UIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.UIFigure);
            app.NSpinner.Step = 5;
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kitaev_spektar_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end