classdef kitaev_valna_funkcija_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        td         matlab.ui.control.Slider
        muLabel_2  matlab.ui.control.Label
        mu         matlab.ui.control.Slider
        muLabel    matlab.ui.control.Label
        UIAxes     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: mu
        function muValueChanged(app, event)
            N = 25; %broj fermionskih siteova
                        
            D=1; %supravodljivi clan
            t=app.td.Value*D; %hopping
            mu = app.mu.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end

        % Value changing function: mu
        function muValueChanging(app, event)
            N = 25; %broj fermionskih siteova
                        
            D=1; %supravodljivi clan
            t=app.td.Value*D; %hopping
            mu = event.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end

        % Value changed function: td
        function tdValueChanged(app, event)
            N = 25; %broj fermionskih siteova
                        
            D=1; %supravodljivi clan
            t=app.td.Value*D; %hopping
            mu = app.mu.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end

        % Value changing function: td
        function tdValueChanging(app, event)
            N = 25; %broj fermionskih siteova
                        
            D=1; %supravodljivi clan
            t=event.Value*D; %hopping
            mu = app.mu.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            N = 25; %broj fermionskih siteova
                        
            t=app.td.Value; %hopping
            D=1; %supravodljivi clan
            mu = app.mu.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'valna funckija MZM')
            xlabel(app.UIAxes, 'site n')
            ylabel(app.UIAxes, '|psi|^2')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 25];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [1 3 5 7 9 11 13 15 17 19 21 23 25];
            app.UIAxes.XTickLabel = {'1'; '3'; '5'; '7'; '9'; '11'; '13'; '15'; '17'; '19'; '21'; '23'; '25'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.muLabel = uilabel(app.UIFigure);
            app.muLabel.Text = 'mu/t';
            app.mu = uislider(app.UIFigure);
            app.mu.Limits = [0 3];
            app.mu.ValueChangedFcn = createCallbackFcn(app, @muValueChanged, true);
            app.mu.ValueChangingFcn = createCallbackFcn(app, @muValueChanging, true);
            app.muLabel_2 = uilabel(app.UIFigure);
            app.muLabel_2.Text = 't/d';
            app.td = uislider(app.UIFigure);
            app.td.Limits = [0 5];
            app.td.ValueChangedFcn = createCallbackFcn(app, @tdValueChanged, true);
            app.td.ValueChangingFcn = createCallbackFcn(app, @tdValueChanging, true);
            app.td.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kitaev_valna_funkcija_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end