classdef valna_funkcija_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        mu        matlab.ui.control.Slider
        muLabel   matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: mu
        function muValueChanged(app, event)
            Value = app.mu.Value;
            N = 25; %broj fermionskih siteova
                        
            t=1; %hopping
            D=t; %supravodljivi clan
            mu = app.mu.Value*t;
            
            %hamiltonijan
            Em=[-mu 0; 0 mu];
            Tm=[-t -D; D t];
            H = kron(diag(ones(1,N)),Em)... 
                +kron(diag(ones(1,N-1),1),Tm)...
                +kron(diag(ones(1,N-1),-1),Tm');
            
            %izdvajanje valnih funkcija od MZM (sredisnja dva)
            [V,D] = eig(H);
            
            psi1 = V(1:2:2*N, N);
            P1 = psi1.*conj(psi1);
            
            psi2 = V(1:2:2*N, N+1);
            P2 = psi2.*conj(psi2);
            
            %prvo uzbudeno stanje se nalazi na N-1 i N+2, itd...
            plot(app.UIAxes, 2*(P1+P2), 'b');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'valna funckija MZM')
            xlabel(app.UIAxes, 'site n')
            ylabel(app.UIAxes, '|psi|^2')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 25];
            app.UIAxes.YLim = [0 1];
            app.muLabel = uilabel(app.UIFigure);
            app.muLabel.Text = 'mu';
            app.mu = uislider(app.UIFigure);
            app.mu.Limits = [0 3];
            app.mu.ValueChangedFcn = createCallbackFcn(app, @muValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = valna_funkcija_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end